classdef registrarse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RegistrarseButton        matlab.ui.control.Button
        SexoDropDown             matlab.ui.control.DropDown
        SexoDropDownLabel        matlab.ui.control.Label
        EdadEditField            matlab.ui.control.NumericEditField
        EdadEditFieldLabel       matlab.ui.control.Label
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        EmailEditField           matlab.ui.control.EditField
        EmailEditFieldLabel      matlab.ui.control.Label
        ApellidoEditField        matlab.ui.control.EditField
        ApellidoEditFieldLabel   matlab.ui.control.Label
        NombreEditField          matlab.ui.control.EditField
        NombreEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegistrarseButton
        function RegistrarseButtonPushed(app, event)
            Nombre = string(app.NombreEditField.Value);
            Apellido = string(app.ApellidoEditField.Value);
            Sexo = string(app.SexoDropDown.Value);
            Edad = string(app.EdadEditField.Value);
            
            Datuak = table(Nombre, Apellido, Sexo, Edad);
            filename = 'App.xlsx';

            
            datos=readtable(filename);
            [fila,~]=size(datos);
            celda=strcat('A',string(fila+2));
            writetable(Datuak,filename,'sheet',1,'Range',celda,'WritevariableNames',false)
            app.registrase.Visible=false;
            app.iniciosesion.Visible=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoEditFieldLabel = uilabel(app.UIFigure);
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.UIFigure, 'text');
            app.EmailEditFieldLabel = uilabel(app.UIFigure);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.SexoDropDownLabel = uilabel(app.UIFigure);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.UIFigure);
            app.SexoDropDown.Items = {'Mujer', 'Hombre', 'Otro'};
            app.SexoDropDown.Value = 'Mujer';
            app.RegistrarseButton = uibutton(app.UIFigure, 'push');
            app.RegistrarseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarseButtonPushed, true);
            app.RegistrarseButton.Text = 'Registrarse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end